classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Gauge        matlab.ui.control.Gauge
        GaugeLabel   matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        Button       matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serialObj          % Serial Port
        timeData           % Dữ liệu thời gian
        speedData          % Dữ liệu tốc độ
        startTime          % Thời gian bắt đầu
    end
    
    methods (Access = private)
        
        function onOffButtonPushed(app,event)
             if strcmp(app.ONOFFButton.Text, 'ON')
                write(app.serialObj, uint8([1]), "uint8"); % Gửi lệnh ON
                app.ONOFFButton.Text = 'OFF';
            else
                write(app.serialObj, uint8([0]), "uint8"); % Gửi lệnh OFF
                app.ONOFFButton.Text = 'ON';
            end
            
        end

        % Callback khi thay đổi giá trị của slider
        function SpeedSliderValueChanged(app, event)
            speedVal = round(app.SpeedSlider.Value);
            write(app.serialObj, [2 uint8(speedVal)], "uint8"); % Gửi lệnh với tốc độ
        end
        
        % Callback cập nhật dữ liệu cho đồ thị thời gian thực
        function updatePlot(app)
            if app.serialObj.NumBytesAvailable > 0
                data = readline(app.serialObj);  % Đọc dữ liệu từ COM
                speed = str2double(data);  % Chuyển đổi dữ liệu

                % Cập nhật đồ thị
                app.timeData(end+1) = toc(app.startTime);
                app.speedData(end+1) = speed;
                plot(app.UIAxes, app.timeData, app.speedData);
            end
        end
        
        % Cập nhật tốc độ cho đồng hồ
        function updateSpeedGauge(app)
            if app.serialObj.NumBytesAvailable > 0
                data = readline(app.serialObj);  % Đọc dữ liệu từ COM
                speed = str2double(data);
                app.SpeedGauge.Value = speed;  % Cập nhật giá trị vào gauge
            end
        end
        
        % Hàm khởi tạo kết nối Serial
        function setupSerial(app)
            app.serialObj = serialport("COM3", 115200);  % COM có thể thay đổi
            configureTerminator(app.serialObj, "LF");    % hoặc CRLF, tùy code bên F28069M
            flush(app.serialObj);                        % clear buffer
        end
        
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end